classdef happOrSad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Label                matlab.ui.control.Label
        HappyorSadLabel      matlab.ui.control.Label
        EnterButton          matlab.ui.control.Button
        ValueEditField       matlab.ui.control.NumericEditField
        ValueEditFieldLabel  matlab.ui.control.Label
        EnteraValueAbove50HappyBelow50SadLabel  matlab.ui.control.Label
        Image                matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("HappyOrSad_app\images\","-begin","-frozen");
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            value = app.ValueEditField.Value;
            
            if value > 50
                app.Image.ImageSource = 'images/happy.png';
                app.Label.Text = "Happy :)";
                app.Label.FontColor = "green";
            elseif value < 50
                app.Image.ImageSource = 'images/sad.jpg';
                app.Label.Text = "Sad :(";
                app.Label.FontColor = "black";
            elseif value == 50
                app.Image.ImageSource = 'confused.jpg';
                app.Label.Text = "Confused ?!";
                app.Label.FontColor = "magenta";
            end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'confused.jpg';
            app.EnteraValueAbove50HappyBelow50SadLabel = uilabel(app.UIFigure);
            app.EnteraValueAbove50HappyBelow50SadLabel.Text = {'Enter a Value:'; 'Above 50 => Happy :)'; 'Below 50 => Sad :('};
            app.ValueEditFieldLabel = uilabel(app.UIFigure);
            app.ValueEditFieldLabel.Text = 'Value:';
            app.ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.HappyorSadLabel = uilabel(app.UIFigure);
            app.HappyorSadLabel.Text = 'Happy or Sad ?!';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = happOrSad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end