classdef SimpleCalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ErrorState                   matlab.ui.control.Label
        ErrorConsoleLabel            matlab.ui.control.Label
        SimpleCalculatorLabel        matlab.ui.control.Label
        CalculateButton              matlab.ui.control.Button
        ChooseOperationListBox       matlab.ui.control.ListBox
        ChooseOperationListBoxLabel  matlab.ui.control.Label
        ResultLabel                  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        OpLabel                      matlab.ui.control.Label
        num2                         matlab.ui.control.NumericEditField
        num1                         matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OpLabel.Text = num2str(app.ChooseOperationListBox.Value);
            app.ResultLabel.Text = "0";
        end

        % Value changed function: num1
        function num1ValueChanged(app, event)
            value = app.num1.Value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            num1 = app.num1.Value;
            num2 = app.num2.Value;
            op = app.ChooseOperationListBox.Value;
            OperationDone = 1;
            
            switch op
            case '+'
                result = num1 + num2;
            case '-'
                result = num1 - num2;
            case '*'
                result = num1 * num2;
            case '/'
                if num2 == 0
                    app.ErrorState.Text = "Error: Divison By Zero"
                    OperationDone = 0;
                else
                    result = num1 / num2;
                end
            end
            
            if OperationDone == 1
                app.ResultLabel.Text = num2str(result);
            else
                app.ResultLabel.Text = "Error";
            end
                
        end

        % Value changed function: ChooseOperationListBox
        function ChooseOperationListBoxValueChanged(app, event)
            value = app.ChooseOperationListBox.Value;
            app.OpLabel.Text = num2str(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.num1 = uieditfield(app.UIFigure, 'numeric');
            app.num1.ValueChangedFcn = createCallbackFcn(app, @num1ValueChanged, true);
            app.num2 = uieditfield(app.UIFigure, 'numeric');
            app.OpLabel = uilabel(app.UIFigure);
            app.OpLabel.Text = 'Op';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.ChooseOperationListBoxLabel = uilabel(app.UIFigure);
            app.ChooseOperationListBoxLabel.Text = 'Choose Operation:';
            app.ChooseOperationListBox = uilistbox(app.UIFigure);
            app.ChooseOperationListBox.Items = {'+', '-', '*', '/'};
            app.ChooseOperationListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseOperationListBoxValueChanged, true);
            app.ChooseOperationListBox.Value = '+';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SimpleCalculatorLabel = uilabel(app.UIFigure);
            app.SimpleCalculatorLabel.Text = 'Simple Calculator';
            app.ErrorConsoleLabel = uilabel(app.UIFigure);
            app.ErrorConsoleLabel.Text = 'Error Console:';
            app.ErrorState = uilabel(app.UIFigure);
            app.ErrorState.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleCalc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end